classdef CalciumTraces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalciumTracesUIFigure     matlab.ui.Figure
        ShowFitsCB                matlab.ui.control.CheckBox
        ShowAveragesCB            matlab.ui.control.CheckBox
        SaveTracesAs_csv_PB       matlab.ui.control.Button
        lagEF                     matlab.ui.control.NumericEditField
        WithlagframesLabel        matlab.ui.control.Label
        FittypeDropDown           matlab.ui.control.DropDown
        FittypeDropDownLabel      matlab.ui.control.Label
        CrossCorrelationButton    matlab.ui.control.Button
        TraceTable                matlab.ui.control.Table
        ShowallButton             matlab.ui.control.Button
        ShowLegendCB              matlab.ui.control.CheckBox
        PlottedROIsList           matlab.ui.control.TextArea
        PlottedROIsTextAreaLabel  matlab.ui.control.Label
        df_Axes                   matlab.ui.control.UIAxes
        TracesAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp     % app    - handle to mainApp
        stack       % array  - imported from mainApp
        stackInfo   % struct - imported from mainApp
        fileName    % string - imported from mainApp
        dataPath    % string - imported from mainApp % Description
        
        interactiveElements % Container for all components that should not be
                            % clicked while something like a new window is going on
        
        activeROIs  % array  - stores the information in app.PlottedROIsList in an easy accesible array
                    %           Is also used to make sure no duplicate or
                    %           invalid entries are in the text area
        ROIsData    % struct with ROI stuff - see mainApp for description
        newCalc     % boolean - Determines if some new calculation has to be saved
    end
    
    methods (Access = private)
        
        function switchInteractivity(app,status)
            for i = 1:length(app.CalciumTracesUIFigure.Children)
                % Not all Components (e.g. Axes) have the Enable property
                try
                    app.CalciumTracesUIFigure.Children(i).Enable = status;
                catch
                    % Everything is fine
                end
            end     
        end
        
        
        function updatePlot(app)
            plotTraces(app)
            
            if app.ShowAveragesCB.Value
                plotAverages(app)
            end
            
            if app.ShowFitsCB.Value
                plotFits(app)
            end
            
            plot_dF(app)
            fillTable(app)
        end
        
        
        function plotTraces(app)
            %Title, as fileName alone won´t be very useful for saving the
            %image, the folderName should be displayed aswell
            filesepIdx = strfind(app.stackInfo.Filename,filesep);
            folderFile = app.stackInfo.Filename(filesepIdx(end-1)+1:end);
            % The default "Interpreter" would interpret the "_" in the
            % filename as subscript 
            title(app.TracesAxes, sprintf("Mean ROI-fluorescence of  '%s '", ...
                folderFile), 'Interpreter', 'none')
            
            % cla to not plot on top of the old traces, as we use hold
            cla(app.TracesAxes)
            
            if isnan(app.activeROIs)
                return
            else
                hold(app.TracesAxes,'on')
                for i = 1:length(app.activeROIs)
                    currROI = app.activeROIs(i);
                    % There are 7 default plotting colors Matlab cycles
                    % through - by setting this colorIndex (=SeriesIndex)
                    % manually the plotted trace has the same color as the
                    % fit - without assigning colors manually
                    plot(app.TracesAxes, app.ROIsData.traces(:,currROI),'SeriesIndex',rem(i,8))
                    text(app.TracesAxes, app.stackInfo.nFrames+2, app.ROIsData.traces(end,currROI), ...
                        num2str(currROI))
                end
                % The label of the average trace would otherwise reach over
                % the edge of the plot
                if app.ShowAveragesCB.Value
                    xlimAdd = 25;
                else
                    xlimAdd = 10;
                end
                xlim(app.TracesAxes, [0,app.stackInfo.nFrames + xlimAdd])
                ylim(app.TracesAxes,'padded'); 
                xlabel(app.TracesAxes,sprintf('Frames, at fps of %.2f Hz', app.stackInfo.fps))
                ylabel(app.TracesAxes,'Fluorescence [a.u.]')
                
                checkLegend(app)
            end
        end
        
        
        function checkLegend(app)
            if app.ShowLegendCB.Value
                % The plotted fits would get their own legend, so the
                % legend is not allowed to update
                legend(app.TracesAxes, string(app.activeROIs),'AutoUpdate','off');
            else
                % Otherwise an empty/arbitrary legend remains
                legend(app.TracesAxes,'off')
            end
        end
        

        function plotAverages(app)
            plot(app.TracesAxes, app.ROIsData.averageF, 'k')
            text(app.TracesAxes, app.stackInfo.nFrames+2, app.ROIsData.averageF(end), ...
                'av')
            
            if isfield(app.ROIsData, 'averageF_ROIs')
                plot(app.TracesAxes, app.ROIsData.averageF_ROIs, 'k-')
                text(app.TracesAxes, app.stackInfo.nFrames+2, app.ROIsData.averageF_ROIs(end), ...
                    'av(ROIs)')
            end
            
            if isfield(app.ROIsData, 'averageFit_yFitted')
                plot(app.TracesAxes, app.ROIsData.averageFit_yFitted, 'k--')
                text(app.TracesAxes, app.stackInfo.nFrames+2, app.ROIsData.averageFit_yFitted(end), ...
                    'av(ROIs) fit')
            end
            
            % This was a try to estimate the autoflourescence by looking at non ROI /
            % non background pixels, but for now it was not as useful as
            % hoped
%             if isfield(app.ROIsData, 'averageF_af')
%                 plot(app.TracesAxes, app.ROIsData.averageF_af, 'k')
%                 text(app.TracesAxes, app.stackInfo.nFrames+2, app.ROIsData.averageF_af(end), ...
%                     'af')
%             end
            
            if isfield(app.ROIsData, 'backgroundValue')
                plot(app.TracesAxes, 1:app.stackInfo.nFrames, ...
                    ones(app.stackInfo.nFrames)*app.ROIsData.backgroundValue, 'k')     
                text(app.TracesAxes, app.stackInfo.nFrames+2, app.ROIsData.backgroundValue, ...
                    'b')
            end
        end
            
                
        function plotFits(app)
            for i = 1:length(app.activeROIs)
                if strcmp(app.FittypeDropDown.Value, 'adapted')
                    plot(app.TracesAxes, ...
                        app.ROIsData.adaptedFit_yFitted(:,app.activeROIs(i)) + app.ROIsData.backgroundValue, ...
                        'SeriesIndex',rem(i,8))
                elseif strcmp(app.FittypeDropDown.Value,'exponential')
                    plot(app.TracesAxes, ...
                        app.ROIsData.expFit_yFitted(:,app.activeROIs(i)) + app.ROIsData.backgroundValue, ...
                        'SeriesIndex',rem(i,8))
                elseif strcmp(app.FittypeDropDown.Value,'linear')
                    plot(app.TracesAxes, ...
                        app.ROIsData.linFit_yFitted(:,app.activeROIs(i)) + app.ROIsData.backgroundValue, ...
                        'SeriesIndex',rem(i,8))
                elseif strcmp(app.FittypeDropDown.Value,'background')
                    % the background is already plotted anyway
                else
                    error(['Somehow the selection of your fit type did not work out, ...' ...
                        'please close this window and all other windows. ...' ...
                        'Then try again - if it stil does not work: open SegmentationGUI, ...' ...
                        'change something (,close it,) and come back to the Calcium traces'])
                end
            end
        end
        
        
        function plot_dF(app)
            %Title, as fileName alone won´t be very useful for saving the
            %image, the folderName should be displayed aswell
            filesepIdx = strfind(app.stackInfo.Filename,filesep);
            folderFile = app.stackInfo.Filename(filesepIdx(end-1)+1:end);
            title(app.df_Axes, sprintf("df/F0 of  '%s '", folderFile), ...
            'Interpreter', 'none')

            cla(app.df_Axes)
            hold(app.df_Axes,'on')
            
            if strcmp(app.FittypeDropDown.Value, 'adapted')
                dfF0 = app.ROIsData.adaptedFit_dfF0;
            elseif strcmp(app.FittypeDropDown.Value, 'exponential')
                dfF0 = app.ROIsData.expFit_dfF0;
            elseif strcmp(app.FittypeDropDown.Value, 'linear')
                dfF0 = app.ROIsData.linFit_dfF0;
            elseif strcmp(app.FittypeDropDown.Value, 'background')
                dfF0 = app.ROIsData.background_dfF0;          
            end
                
             for i = 1:length(app.activeROIs)
                plot(app.df_Axes, dfF0(:,app.activeROIs(i)),'SeriesIndex',rem(i,8))
                text(app.df_Axes, app.stackInfo.nFrames+2, dfF0(end,app.activeROIs(i)), ...
                    num2str(app.activeROIs(i)))
             end

            xlim(app.df_Axes, [0,app.stackInfo.nFrames + 10])
            % The "padded" makes sure, that the plot is displayed
            % correctly, with some "padding"
            ylim(app.df_Axes,'padded');
            ylims = ylim(app.df_Axes);
            upYlim = max(abs(ylims));
            if ylims(1) > 0 
                lowYlim = 0;
            else
                lowYlim = ylims(1);
            end
            ylim(app.df_Axes, [lowYlim, upYlim])
            xlabel(app.df_Axes,sprintf('Frames, at fps of %.2f Hz', app.stackInfo.fps))
            ylabel(app.df_Axes,'Fluorescence ratio')
                
        end
        
        
        function readROIsList(app)
            % The text area stores all numbers as cell-strings with ','
            % inbetween the ROI-values, so we need to split them at every ','. 
            % Also as it is a cell and every row is a new cell containing text. (therefore {r}) 
            % str2double gives an array with the numbers -> convenient
            % Preallcation does not work as we can´t read the cell-contents directly
            nRows = length(app.PlottedROIsList.Value);
            app.activeROIs = [];
            for r = 1:nRows
                app.activeROIs = [app.activeROIs, ...
                    str2double(strsplit(app.PlottedROIsList.Value{r},{',',' '}))];
            end
            % After assigning values we discard values that occured more
            % than once (bad user input)
            app.activeROIs = unique(app.activeROIs);
            % Then we get rid of the "0" and of potential NaN aswell
            app.activeROIs = app.activeROIs(app.activeROIs > 0);
            % Now we make sure no number exeeds the number of available ROIsw
            app.activeROIs = app.activeROIs(app.activeROIs <= size(app.ROIsData.traces,2));
            
            % Now the Text Area is updated so the user sees the correct input
            app.PlottedROIsList.Value = num2str(app.activeROIs);
        end
        
        
        function fillTable(app)
            app.TraceTable.ColumnName = {'ROI','StartF','MaxF','d_stim[pix]','d_stim[µm]'};
            % as the arrayfun outputs a cell array and the columns should
            % be the same type - num2cell was the easiest solution
            if ~isempty(app.activeROIs)
                data(:,1) = arrayfun(@(x)sprintf('%-.f',x),app.activeROIs,'UniformOutput',false);
                data(:,2) = arrayfun(@(x)sprintf('%-.f',x),app.ROIsData.traces(1,app.activeROIs),'UniformOutput',false);
                data(:,3) = arrayfun(@(x)sprintf('%-.f',x),max(app.ROIsData.traces(:,app.activeROIs)),'UniformOutput',false);
                if isfield(app.ROIsData,'dStim')
                    data(:,4) = arrayfun(@(x)sprintf('%.f',x),app.ROIsData.dStim(app.activeROIs),'UniformOutput',false);
                    % x and y pixelsize should be the same, but I don´t dare
                    % renaming it into pixelSize to prevent forgetting this
                    % "convention" of just assuming they are equal
                    data(:,5) = arrayfun(@(x)sprintf('%.f',x),app.ROIsData.dStim(app.activeROIs)*app.stackInfo.xPixelSize,'UniformOutput',false);
                end
                app.TraceTable.Data = data;
            else
                app.TraceTable.Data = [];
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            % So no user interaction while loading (relevant when fitting)
            switchInteractivity(app,0)
            app.newCalc = 0;
            
            app.mainApp = mainApp;
            app.stack = mainApp.stack;
            app.stackInfo = mainApp.stackInfo;
            app.fileName = mainApp.fileName;
            app.dataPath = mainApp.dataPath;
            app.ROIsData = mainApp.ROIsData;
            
            disableDefaultInteractivity(app.TracesAxes)
            app.TracesAxes.Toolbar.Visible = false;
            disableDefaultInteractivity(app.df_Axes)
            app.df_Axes.Toolbar.Visible = false;
            
            app.PlottedROIsList.Value = num2str(1:3);
            readROIsList(app)
            updatePlot(app)
            
            switchInteractivity(app,1)
        end

        % Value changed function: FittypeDropDown
        function FittypeDropDownValueChanged(app, event)
            updatePlot(app)
        end

        % Value changed function: PlottedROIsList
        function PlottedROIsListValueChanged(app, event)
            readROIsList(app)
            updatePlot(app)
        end

        % Value changed function: ShowLegendCB
        function ShowLegendCBValueChanged(app, event)
            checkLegend(app)
        end

        % Value changed function: ShowAveragesCB
        function ShowAveragesCBValueChanged(app, event)
            updatePlot(app)
        end

        % Value changed function: ShowFitsCB
        function ShowFitsCBValueChanged(app, event)
            updatePlot(app)
        end

        % Button pushed function: ShowallButton
        function ShowallButtonPushed(app, event)
            % This functions first fills the table and then reads from it
            % to make sure everything fits together
            app.PlottedROIsList.Value = num2str(1:size(app.ROIsData.traces,2));
            readROIsList(app)
            updatePlot(app)
        end

        % Button pushed function: SaveTracesAs_csv_PB
        function SaveTracesAs_csv_PBPushed(app, event)
            writematrix(round(app.ROIsData.traces,2), fullfile(app.dataPath,[app.fileName, '_Traces.csv']))
            writematrix(round(app.ROIsData.background_dfF0,2), fullfile(app.dataPath,[app.fileName, '_background_dfF0.csv']))
            writematrix(round(app.ROIsData.linFit_dfF0,2), fullfile(app.dataPath,[app.fileName, '_linFit_dfF0.csv']))
            writematrix(round(app.ROIsData.expFit_dfF0,2), fullfile(app.dataPath,[app.fileName, '_expFit_dfF0.csv']))
            writematrix(round(app.ROIsData.adaptedFit_dfF0,2), fullfile(app.dataPath,[app.fileName, '_adaptedFit_dfF0.csv']))
            try
                winopen(app.dataPath)
            catch e
                errordlg(['Most likely this was caused by not using Windows - So your saving worked, but you have to navigate to the folder yourself ...' ...
                    'It is the same folder that houses all the .mat files. Have fun!'])
                disp(e)
            end
        end

        % Button pushed function: CrossCorrelationButton
        function CrossCorrelationButtonPushed(app, event)
            if strcmp(app.FittypeDropDown.Value, 'adapted')
                dfF0 = app.ROIsData.adaptedFit_dfF0;
            elseif strcmp(app.FittypeDropDown.Value, 'exponential')
                dfF0 = app.ROIsData.expFit_dfF0;
            elseif strcmp(app.FittypeDropDown.Value, 'linear')
                dfF0 = app.ROIsData.linFit_dfF0;
            elseif strcmp(app.FittypeDropDown.Value, 'background')
                dfF0 = app.ROIsData.background_dfF0;                
            end
            
            lag = app.lagEF.Value;
            xcorrs = zeros(app.ROIsData.nROIs, app.ROIsData.nROIs, 2*lag+1);
            for corrSource = 1:app.ROIsData.nROIs
                for corrTarget = 1:app.ROIsData.nROIs
                    % Getting rid of autoCorrelation and only one
                    % source-target interaction
                    if or(corrSource == corrTarget, corrSource < corrTarget)
                        xcorrs(corrSource,corrTarget,:) = NaN;
                    else
                        xcorrs(corrSource,corrTarget,1:(2*lag+1)) = xcorr(dfF0(:,corrSource),dfF0(:,corrTarget),lag);
                    end
                end
            end
            % if the lower cLim is equal to the smallest value, the
            % smallest value will be black due to the color map, instead of
            % the correct color (see customColorMap)
            minCorr = min(xcorrs,[],'all');
            cLims = [minCorr-0.1*abs(minCorr), max(xcorrs,[],'all')];
            
            figure('Name','xcorr', 'NumberTitle','off','Position',[80,80,1200,400])
            % So values we don´t want to show are displayed as black.
            % Autocorrelation and x-y / y-x doubles
            customColormap = colormap("parula");
            customColormap(1,:) = [0 0 0];
            colormap(customColormap)
            sgtitle(sprintf('xcorr of %s dfF0', app.FittypeDropDown.Value))
            for idx=1:3
                subplot(1,3,idx);
                imagesc(xcorrs(:,:,1+(idx-1)*lag),cLims)
                ax = gca;
                disableDefaultInteractivity(ax)
                ax.Toolbar.Visible = false;
                axis equal
                curLag = -lag + lag*(idx-1);
                title(sprintf('lag of %g frames (%g s)', curLag, round(curLag*app.stackInfo.fps,2)))
                ylabel('Source ROI')
                xlabel('Target ROI')
                colorbar('Limits',cLims)
            end
            drawnow()
        end

        % Close request function: CalciumTracesUIFigure
        function CalciumTracesUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalciumTracesUIFigure = uifigure('Visible', 'off');
            app.CalciumTracesUIFigure.Name = 'CalciumTraces';
            app.CalciumTracesUIFigure.CloseRequestFcn = createCallbackFcn(app, @CalciumTracesUIFigureCloseRequest, true);
            app.TracesAxes = uiaxes(app.CalciumTracesUIFigure);
            title(app.TracesAxes, 'Title')
            xlabel(app.TracesAxes, 'X')
            ylabel(app.TracesAxes, 'Y')
            zlabel(app.TracesAxes, 'Z')
            app.df_Axes = uiaxes(app.CalciumTracesUIFigure);
            title(app.df_Axes, 'Title')
            xlabel(app.df_Axes, 'X')
            ylabel(app.df_Axes, 'Y')
            zlabel(app.df_Axes, 'Z')
            app.PlottedROIsTextAreaLabel = uilabel(app.CalciumTracesUIFigure);
            app.PlottedROIsTextAreaLabel.Text = {'Plotted '; 'ROIs'};
            app.PlottedROIsList = uitextarea(app.CalciumTracesUIFigure);
            app.PlottedROIsList.ValueChangedFcn = createCallbackFcn(app, @PlottedROIsListValueChanged, true);
            app.ShowLegendCB = uicheckbox(app.CalciumTracesUIFigure);
            app.ShowLegendCB.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCBValueChanged, true);
            app.ShowLegendCB.Text = 'Show Legend';
            app.ShowallButton = uibutton(app.CalciumTracesUIFigure, 'push');
            app.ShowallButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallButtonPushed, true);
            app.ShowallButton.Text = 'Show all';
            app.TraceTable = uitable(app.CalciumTracesUIFigure);
            app.TraceTable.ColumnName = {'C1'; 'C2'; 'C3'; 'C4'; 'C5'};
            app.TraceTable.RowName = {};
            app.CrossCorrelationButton = uibutton(app.CalciumTracesUIFigure, 'push');
            app.CrossCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @CrossCorrelationButtonPushed, true);
            app.CrossCorrelationButton.Text = 'CrossCorrelation';
            app.FittypeDropDownLabel = uilabel(app.CalciumTracesUIFigure);
            app.FittypeDropDownLabel.Text = 'Fit type';
            app.FittypeDropDown = uidropdown(app.CalciumTracesUIFigure);
            app.FittypeDropDown.Items = {'background', 'linear', 'exponential', 'adapted'};
            app.FittypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FittypeDropDownValueChanged, true);
            app.FittypeDropDown.Value = 'background';
            app.WithlagframesLabel = uilabel(app.CalciumTracesUIFigure);
            app.WithlagframesLabel.Text = 'With lag (+/- frames)';
            app.lagEF = uieditfield(app.CalciumTracesUIFigure, 'numeric');
            app.lagEF.Value = 5;
            app.SaveTracesAs_csv_PB = uibutton(app.CalciumTracesUIFigure, 'push');
            app.SaveTracesAs_csv_PB.ButtonPushedFcn = createCallbackFcn(app, @SaveTracesAs_csv_PBPushed, true);
            app.SaveTracesAs_csv_PB.Text = {'SaveTracesAsSCV'; '(Language of CSV program'; 'must be English!)'};
            app.ShowAveragesCB = uicheckbox(app.CalciumTracesUIFigure);
            app.ShowAveragesCB.ValueChangedFcn = createCallbackFcn(app, @ShowAveragesCBValueChanged, true);
            app.ShowAveragesCB.Text = 'Show Averages';
            app.ShowFitsCB = uicheckbox(app.CalciumTracesUIFigure);
            app.ShowFitsCB.ValueChangedFcn = createCallbackFcn(app, @ShowFitsCBValueChanged, true);
            app.ShowFitsCB.Text = 'Show Fits';
            % Show the figure after all components are created
            app.CalciumTracesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalciumTraces(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalciumTracesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalciumTracesUIFigure)
        end
    end
end