classdef SegmentationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DeleteROIsminSizePB         matlab.ui.control.Button
        ROIsMinSize_Label           matlab.ui.control.Label
        ROIs_Label                  matlab.ui.control.Label
        DeleteROILineButton         matlab.ui.control.Button
        DeleteROIFreehandButton     matlab.ui.control.Button
        AddROIFreehandButton        matlab.ui.control.Button
        ROI_ID_BG_CB                matlab.ui.control.CheckBox
        MinSizeROI_EF               matlab.ui.control.NumericEditField
        ROIminSizePixelsLabel       matlab.ui.control.Label
        ROI_ID_CB                   matlab.ui.control.CheckBox
        ROI_OpacityEF               matlab.ui.control.NumericEditField
        ROIOpacityLabel             matlab.ui.control.Label
        ROI_OpacitySlider           matlab.ui.control.Slider
        StackOpacityEF              matlab.ui.control.NumericEditField
        StackOpacityEditFieldLabel  matlab.ui.control.Label
        StackOpacitySlider          matlab.ui.control.Slider
        cMaxEF                      matlab.ui.control.NumericEditField
        cMaxEFLabel                 matlab.ui.control.Label
        cMinEF                      matlab.ui.control.NumericEditField
        cMinEFLabel                 matlab.ui.control.Label
        StackTypeDropDown           matlab.ui.control.DropDown
        StackTypeDropDownLabel      matlab.ui.control.Label
        SegmentationACusesMinROISizeButton  matlab.ui.control.Button
        StackAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainApp     % app    - handle to mainApp
        stack       % array  - imported from mainApp
        stackInfo   % struct - imported from mainApp
        fileName    % struct - imported from mainApp
        filePath    % string - imported from mainApp
        dataPath    % string - imported from mainApp
        
        ROIsData    % struct - similar to stackInfo, but for ROIs, is also used
                    %           for saving and loading
            %nROIs      double - number of ROIs
            %ROIs       array - Stores a matrix of the same x-y size as the stack
                            % with labeled (1,2,3,...) ROIs
            %ROI_Sizes  array - stores the number of pixels per ROI
        
        
        cLimit % double - Limit of the value for colorbar, should be between 0 and 65535
        currStack % array (Width x Height) - Stack for plotting (Mean,Median,StD)
        
        changes % If no changes occure there is no reason to save when exiting
        
    end
    
    methods (Access = private)
        
        function switchInteractivity(app,status)
            for i = 1:length(app.UIFigure.Children)
                % Not all Components (e.g. Axes) have the Enable property
                try
                    app.UIFigure.Children(i).Enable = status;
                catch
                    % Everything is fine
                end
            end     
        end
        
        
        function plotStack(app)
            % Clims have to be specified last
            imagesc(app.currStack, 'Parent', app.StackAxes, ...
                'AlphaData', app.StackOpacityEF.Value/100, ...
                [app.cMinEF.Value app.cMaxEF.Value]);
            % plot the ROIs
            hold(app.StackAxes,'on')
            title(app.StackAxes, sprintf("%s of %s \t ",app.StackTypeDropDown.Value, app.fileName),'Interpreter','none');
            % The field exists anyway as it is defined in the properties of
            % the app, even no value was ever assigned
            if isfield(app.ROIsData,'ROIs')
                plotROIs(app)
            end
            hold(app.StackAxes,'off')
        end
        
        
        function plotROIs(app)
            ROI_bounds = bwboundaries(app.ROIsData.ROIs);
            try
                for i=1:numel(ROI_bounds)
                    % ROIs below minSize will be plotted differently to show
                    % which ones would be considered too small before removing
                    % them
                    if app.ROIsData.sizes(i) >= app.MinSizeROI_EF.Value
                    plot(ROI_bounds{i}(:,2), ROI_bounds{i}(:,1), ...
                        'Parent', app.StackAxes, ...
                        'Color', [1 0 0 app.ROI_OpacityEF.Value/100]);                    
                    else
                    plot(ROI_bounds{i}(:,2), ROI_bounds{i}(:,1), ...
                        'Parent', app.StackAxes, ...
                        'Color', [0 0 0 app.ROI_OpacityEF.Value/100]);                     
                    end
                end
            catch
                errordlg(['ROI saving did not work properly and' ...
                    'therefore number of ROIs is ill-defined.' ...
                    'Please don´t jump around windows, interrupt ROI drawing' ...
                    '  or open the Segmentation GUI more than once!'],'Error','ok')
            end
            
            if app.ROI_ID_CB.Value == true
                if app.ROI_ID_BG_CB.Value == 1
                    bgC = [1 1 1];
                else
                    bgC = 'none';
                end
                for i=1:app.ROIsData.nROIs
                    text(ROI_bounds{i}(1,2),ROI_bounds{i}(1,1),num2str(i), ...
                        'Color','k', 'FontSize',12 , 'FontWeight','bold',...
                        'BackgroundColor',bgC, 'Parent',app.StackAxes);
                end
            end
            
        end
        
        
        function selectStack(app,stackType)
            if strcmp(stackType, 'Mean')
                app.currStack = app.stackInfo.mean;
                cMin = app.stackInfo.cMin;
                cMax = app.stackInfo.cMax;
            elseif strcmp(stackType,'Median')
                app.currStack = app.stackInfo.median;
                cMin = app.stackInfo.cMin;
                cMax = app.stackInfo.cMax;                
            elseif strcmp(stackType,'StD')
                app.currStack = app.stackInfo.std;
                % Needs to be a rounded double for the EF, did not find a
                % neater solution
                cMin = double(round(quantile(app.currStack, 0.15, 'all')));
                cMax = double(round(quantile(app.currStack, 0.95, 'all')));
            end
            app.cMinEF.Limits = app.cLimit;
            app.cMaxEF.Limits = app.cLimit;
            app.cMinEF.Value = cMin;
            app.cMaxEF.Value = cMax;
        end
        
        
        function cLimitChange(app)
            % There needs to be a "range"
            if abs(app.cMinEF.Value - app.cMaxEF.Value) == 0
                % If lower value is zero - it can´t be decreased
                if app.cMinEF.Value == app.cLimit(1)
                    app.cMaxEF.Value = app.cMinEF.Value +1;
                else
                    app.cMinEF.Value = app.cMaxEF.Value -1;
                end
            end
            
            app.cMinEF.Limits = [app.cLimit(1), app.cMaxEF.Value];
            app.cMaxEF.Limits = [app.cMinEF.Value, app.cLimit(2)];
        end
            
        
        function segmentationAC(app)
            % Identification of ROIs by using the inbuild active contour
            % function, the basis will be the current stack
            % (mean/median/std)
            
            progBar = waitbar(0,'Please wait for "active contour segmentation" to finish');
            
            % Defines the starting points of the AC evolution
            % only the bright spots are a good start
            AC_start = app.currStack > prctile(app.currStack(:),95);
            waitbar(0.1,progBar);
            
            BW = activecontour(app.currStack, AC_start);
            waitbar(0.6, progBar);
            % AC only produces a Black-White (0-1) image
            % bwlabel assigns numbers from 1 - "last ROI"
            % Also imfill works MUCH better after ROIs get differentiated
            % by bwlabel!
            app.ROIsData.ROIs = bwlabel(BW);
            
            waitbar(0.7, progBar);
            deleteSmallROIs(app)
            
            waitbar(0.8, progBar);
            relabelROIs(app)
            
            waitbar(0.9, progBar);
            plotStack(app)
            
            delete(progBar)
        end
        
        
        function deleteSmallROIs(app)
            % imfill fills regions where "0" is inbetween ROIs, e.g. small
            % black dots/region - it´s most likely still the same cell.
            % Not adressing those "0" causes trouble with bwboundaries in
            % plotROI.
            ROIs = imfill(app.ROIsData.ROIs,'holes');
            rp = regionprops(ROIs,'Area');
            A = [rp.Area];
            
            for i=1:length(A)
                if(A(i)<app.MinSizeROI_EF.Value)
                    ROIs(ROIs==i) = 0;
                end
            end
            
            %Sometimes very small ROIs are inbetween bigger ROIs - they
            %won´t be filled, but disapper in the loop, so we fill those
            %holes again
            app.ROIsData.ROIs = imfill(ROIs,'holes');
        end
        
        
        function relabelROIs(app)
            % From delete and add callbacks as they all need relabeling
            % Labeling all ROIs new may seem inefficient, but it is the
            % safest way to make sure the numbering stays correct - Which
            % is also important for the corresponding ROIsData.sizes property!
            app.ROIsData.ROIs = bwlabel(app.ROIsData.ROIs);
            % Similar reason for regionprops - We can´t know if the new ROI
            % will get "the last number", if not the table is out of sync.
            rp = regionprops(app.ROIsData.ROIs,'Area');
            app.ROIsData.sizes = [rp.Area];
            app.ROIsData.nROIs = length(app.ROIsData.sizes);
            
            app.changes = 1;
            ROI_labels_text(app)
        end
        
        
        function ROI_labels_text(app)
            app.ROIs_Label.Text = sprintf('ROIs: %.f', app.ROIsData.nROIs);
            app.ROIsMinSize_Label.Text = ...
                sprintf('ROIs < Min ROI Size: %.f', length(find(app.ROIsData.sizes < app.MinSizeROI_EF.Value)));
            app.DeleteROIsminSizePB.Text = sprintf('Delete ROIs < %.f Pixels', app.MinSizeROI_EF.Value);
        end
        
        
       function updateMainApp(app)
            try
                app.mainApp.updateFilesListBox()
            catch
                errordlg("Could not reload the FilesListBox, click a file in the main window to make changes appear")
            end
        end
        
        function calculateCalciumTraces(app)
            app.ROIsData.traces = zeros(app.stackInfo.nFrames, app.ROIsData.nROIs);
            for t = 1:app.stackInfo.nFrames
                tStack = app.stack(:,:,t);
                for i = 1:app.ROIsData.nROIs
                    app.ROIsData.traces(t,i) = mean(tStack(app.ROIsData.ROIs == i));
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            % So no user interaction while loading (only relevant when slow)
            switchInteractivity(app,0)
            
            app.mainApp = mainApp;
            app.stack = mainApp.stack;
            app.stackInfo = mainApp.stackInfo;
            app.fileName = mainApp.fileName;
            app.filePath = mainApp.filePath;
            app.dataPath = mainApp.dataPath;
            app.ROIsData = mainApp.ROIsData;
            app.cLimit = [0, 65535];
            
            disableDefaultInteractivity(app.StackAxes)
            app.StackAxes.Toolbar.Visible = false;
            % By setting XLim and YLim to the size of the axes, it is
            % ensured that 1 pixel of the image correspods to 1 pixel
            % of the axes --> No distortion
            % Though this will lead to problems if the image is bigger than
            % the limits....
            if or (app.StackAxes.Position(3) > app.stackInfo.Width, ...
                   app.StackAxes.Position(4) < app.stackInfo.Height)
                app.StackAxes.XLim = [0, app.StackAxes.Position(3)];
                app.StackAxes.YLim = [0, app.StackAxes.Position(4)];
            else
                errordlg('The image is bigger than the AxesWindow and is therefore distorted')
            end
            
            
            app.ROI_ID_BG_CB.Enable = 0; % will only be enabled if ROI_IDs are actually displayed
            
            selectStack(app,app.StackTypeDropDown.Value)
            
            % To properly define the cLimits of the EFs
            cLimitChange(app)
            plotStack(app)
            if isfield(app.ROIsData,'sizes')
                ROI_labels_text(app)
            end
            
            
            app.changes = 0;
            switchInteractivity(app,1)
        end

        % Button pushed function: SegmentationACusesMinROISizeButton
        function SegmentationACusesMinROISizeButtonPushed(app, event)
            segmentationAC(app)
        end

        % Value changed function: StackTypeDropDown
        function StackTypeDropDownValueChanged(app, event)
            selectStack(app,app.StackTypeDropDown.Value)
            plotStack(app)
        end

        % Value changed function: cMinEF
        function cMinEFValueChanged(app, event)
            cLimitChange(app)
            plotStack(app);
        end

        % Value changed function: cMaxEF
        function cMaxEFValueChanged(app, event)
            cLimitChange(app)
            plotStack(app);
        end

        % Value changed function: StackOpacityEF
        function StackOpacityEFValueChanged(app, event)
            app.StackOpacitySlider.Value = app.StackOpacityEF.Value;
            plotStack(app)
        end

        % Value changed function: StackOpacitySlider
        function StackOpacitySliderValueChanged(app, event)
            app.StackOpacitySlider.Value = round(app.StackOpacitySlider.Value);
            app.StackOpacityEF.Value = app.StackOpacitySlider.Value;
            plotStack(app)
        end

        % Value changed function: ROI_OpacityEF
        function ROI_OpacityEFValueChanged(app, event)
            app.ROI_OpacitySlider.Value = app.ROI_OpacityEF.Value;
            plotStack(app)
        end

        % Value changed function: ROI_OpacitySlider
        function ROI_OpacitySliderValueChanged(app, event)
            app.ROI_OpacitySlider.Value = round(app.ROI_OpacitySlider.Value);
            app.ROI_OpacityEF.Value = app.ROI_OpacitySlider.Value;
            plotStack(app)
        end

        % Value changed function: ROI_ID_CB
        function ROI_ID_CBValueChanged(app, event)
            plotStack(app)
            if app.ROI_ID_CB.Value == 1
                app.ROI_ID_BG_CB.Enable = 1;
                app.ROI_ID_BG_CB.Visible = 1;
            else
                app.ROI_ID_BG_CB.Visible = 0;
                app.ROI_ID_BG_CB.Enable = 0;
            end
        end

        % Value changed function: ROI_ID_BG_CB
        function ROI_ID_BG_CBValueChanged(app, event)
            plotStack(app)
        end

        % Value changed function: MinSizeROI_EF
        function MinSizeROI_EFValueChanged(app, event)
            ROI_labels_text(app)
            plotStack(app)
        end

        % Button pushed function: DeleteROIsminSizePB
        function DeleteROIsminSizePBPushed(app, event)
            deleteSmallROIs(app)
            relabelROIs(app)
            plotStack(app)
        end

        % Button pushed function: AddROIFreehandButton
        function AddROIFreehandButtonPushed(app, event)
            switchInteractivity(app,0)
            newROI = drawfreehand(app.StackAxes, 'DrawingArea', ... 
                    [0,0,app.stackInfo.Width,app.stackInfo.Height]);
            % Creates a "mask" that contains the "pixels" of the new ROI
            newPix = newROI.createMask;
            delete(newROI)
            newPix = imfill(newPix,'holes');
            app.ROIsData.ROIs = bwlabel(app.ROIsData.ROIs + newPix);
            
            relabelROIs(app)
            plotStack(app)
            switchInteractivity(app,1)
        end

        % Button pushed function: DeleteROIFreehandButton
        function DeleteROIFreehandButtonPushed(app, event)
            switchInteractivity(app,0)
            delROI = drawfreehand(app.StackAxes, 'DrawingArea', ... 
            [0,0,app.stackInfo.Width,app.stackInfo.Height], 'color','r');
            delPix = delROI.createMask;
            delete(delROI)
            app.ROIsData.ROIs(delPix == 1) = 0;
            %Defining connectivity of 4 - only edges - reduces the fill-up
            app.ROIsData.ROIs = imfill(app.ROIsData.ROIs, 4, 'holes');
            
            relabelROIs(app)
            plotStack(app)
            switchInteractivity(app,1)
        end

        % Button pushed function: DeleteROILineButton
        function DeleteROILineButtonPushed(app, event)
            switchInteractivity(app,0)
            delROI = drawline(app.StackAxes, 'DrawingArea', ... 
            [0, 0, app.stackInfo.Width, app.stackInfo.Height],...
            'color','r');
            delPix = delROI.createMask;
            delete(delROI)
            app.ROIsData.ROIs(delPix == 1) = 0;
            
            relabelROIs(app)
            plotStack(app)
            switchInteractivity(app,1)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if app.changes == 1
                    pgbMA = uiprogressdlg(app.mainApp.UIFigure, ...
                        'Title', 'Closing SegmentationGUI',...
                        'Indeterminate','on');
                    
                    pgb = uiprogressdlg(app.UIFigure, ...
                        'Title', 'Closing SegmentationGUI',...
                        'Indeterminate','on');
                    % So nothing happens if someone opens this window and then
                    % closes it before anything is done
                    if ~isempty(app.ROIsData.ROIs)
                        calculateCalciumTraces(app)
                        SaveROIs(app.dataPath, app.fileName, app.ROIsData)
                        updateMainApp(app)
                    end
                    try
                        delete(pgb)
                        delete(pgbMA)
                    catch
                        error('Progress bar was closed before the files could be updated. Segmentation data may be uncomplete')
                    end
                end
            catch e
                disp(e)
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StackAxes = uiaxes(app.UIFigure);
            title(app.StackAxes, {'fileName'; ''})
            app.StackAxes.XTick = [];
            app.StackAxes.YTick = [];
            app.SegmentationACusesMinROISizeButton = uibutton(app.UIFigure, 'push');
            app.SegmentationACusesMinROISizeButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationACusesMinROISizeButtonPushed, true);
            app.SegmentationACusesMinROISizeButton.Text = {'Segmentation - AC'; '(uses Min ROI Size)'};
            app.StackTypeDropDownLabel = uilabel(app.UIFigure);
            app.StackTypeDropDownLabel.Text = 'Stack Type';
            app.StackTypeDropDown = uidropdown(app.UIFigure);
            app.StackTypeDropDown.Items = {'Mean', 'Median', 'StD'};
            app.StackTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StackTypeDropDownValueChanged, true);
            app.StackTypeDropDown.Value = 'Mean';
            app.cMinEFLabel = uilabel(app.UIFigure);
            app.cMinEFLabel.Text = 'cMin';
            app.cMinEF = uieditfield(app.UIFigure, 'numeric');
            app.cMinEF.ValueChangedFcn = createCallbackFcn(app, @cMinEFValueChanged, true);
            app.cMaxEFLabel = uilabel(app.UIFigure);
            app.cMaxEFLabel.Text = 'cMax';
            app.cMaxEF = uieditfield(app.UIFigure, 'numeric');
            app.cMaxEF.ValueChangedFcn = createCallbackFcn(app, @cMaxEFValueChanged, true);
            app.StackOpacitySlider = uislider(app.UIFigure);
            app.StackOpacitySlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.StackOpacitySlider.ValueChangedFcn = createCallbackFcn(app, @StackOpacitySliderValueChanged, true);
            app.StackOpacitySlider.MinorTicks = [];
            app.StackOpacitySlider.Value = 100;
            app.StackOpacityEditFieldLabel = uilabel(app.UIFigure);
            app.StackOpacityEditFieldLabel.Text = 'Stack Opacity';
            app.StackOpacityEF = uieditfield(app.UIFigure, 'numeric');
            app.StackOpacityEF.Limits = [0 100];
            app.StackOpacityEF.ValueChangedFcn = createCallbackFcn(app, @StackOpacityEFValueChanged, true);
            app.StackOpacityEF.Value = 100;
            app.ROI_OpacitySlider = uislider(app.UIFigure);
            app.ROI_OpacitySlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ROI_OpacitySlider.ValueChangedFcn = createCallbackFcn(app, @ROI_OpacitySliderValueChanged, true);
            app.ROI_OpacitySlider.MinorTicks = [];
            app.ROI_OpacitySlider.Value = 100;
            app.ROIOpacityLabel = uilabel(app.UIFigure);
            app.ROIOpacityLabel.Text = 'ROI Opacity';
            app.ROI_OpacityEF = uieditfield(app.UIFigure, 'numeric');
            app.ROI_OpacityEF.Limits = [0 100];
            app.ROI_OpacityEF.ValueChangedFcn = createCallbackFcn(app, @ROI_OpacityEFValueChanged, true);
            app.ROI_OpacityEF.Value = 100;
            app.ROI_ID_CB = uicheckbox(app.UIFigure);
            app.ROI_ID_CB.ValueChangedFcn = createCallbackFcn(app, @ROI_ID_CBValueChanged, true);
            app.ROI_ID_CB.Text = 'Show ROI ID';
            app.ROIminSizePixelsLabel = uilabel(app.UIFigure);
            app.ROIminSizePixelsLabel.Text = 'ROI minSize (Pixels)';
            app.MinSizeROI_EF = uieditfield(app.UIFigure, 'numeric');
            app.MinSizeROI_EF.Limits = [0 Inf];
            app.MinSizeROI_EF.ValueChangedFcn = createCallbackFcn(app, @MinSizeROI_EFValueChanged, true);
            app.MinSizeROI_EF.Value = 25;
            app.ROI_ID_BG_CB = uicheckbox(app.UIFigure);
            app.ROI_ID_BG_CB.ValueChangedFcn = createCallbackFcn(app, @ROI_ID_BG_CBValueChanged, true);
            app.ROI_ID_BG_CB.Visible = 'off';
            app.ROI_ID_BG_CB.Text = 'ROI ID background';
            app.AddROIFreehandButton = uibutton(app.UIFigure, 'push');
            app.AddROIFreehandButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIFreehandButtonPushed, true);
            app.AddROIFreehandButton.Text = 'Add ROI (Freehand)';
            app.DeleteROIFreehandButton = uibutton(app.UIFigure, 'push');
            app.DeleteROIFreehandButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIFreehandButtonPushed, true);
            app.DeleteROIFreehandButton.Text = 'Delete ROI (Freehand)';
            app.DeleteROILineButton = uibutton(app.UIFigure, 'push');
            app.DeleteROILineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROILineButtonPushed, true);
            app.DeleteROILineButton.Text = 'Delete ROI (Line)';
            app.ROIs_Label = uilabel(app.UIFigure);
            app.ROIs_Label.Text = 'ROIs: 0';
            app.ROIsMinSize_Label = uilabel(app.UIFigure);
            app.ROIsMinSize_Label.Text = 'ROIs < minSize: ----';
            app.DeleteROIsminSizePB = uibutton(app.UIFigure, 'push');
            app.DeleteROIsminSizePB.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIsminSizePBPushed, true);
            app.DeleteROIsminSizePB.Text = 'Delete ROIs < minSize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegmentationGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end